classdef CurrentMismatches_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CustomTab                      matlab.ui.container.Tab
        bottomcellLabel_5              matlab.ui.control.Label
        TopcellLabel_7                 matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        JscTandem                      matlab.ui.control.NumericEditField
        JscBot                         matlab.ui.control.NumericEditField
        JscTop                         matlab.ui.control.NumericEditField
        J_scAm2Label                   matlab.ui.control.Label
        VocTandem                      matlab.ui.control.NumericEditField
        VocBot                         matlab.ui.control.NumericEditField
        VocTop                         matlab.ui.control.NumericEditField
        V_ocVLabel                     matlab.ui.control.Label
        PowerBot                       matlab.ui.control.NumericEditField
        PowerTop                       matlab.ui.control.NumericEditField
        PowerTandem                    matlab.ui.control.NumericEditField
        FFBot                          matlab.ui.control.NumericEditField
        FFTop                          matlab.ui.control.NumericEditField
        FFTandem                       matlab.ui.control.NumericEditField
        VmppBot                        matlab.ui.control.NumericEditField
        VmppTop                        matlab.ui.control.NumericEditField
        VmppTandem                     matlab.ui.control.NumericEditField
        JmppTandem                     matlab.ui.control.NumericEditField
        JmppBot                        matlab.ui.control.NumericEditField
        JmppTop                        matlab.ui.control.NumericEditField
        TandemLabel                    matlab.ui.control.Label
        BottomLabel                    matlab.ui.control.Label
        TopLabel                       matlab.ui.control.Label
        PowerWm2Label                  matlab.ui.control.Label
        FFLabel                        matlab.ui.control.Label
        V_mppVLabel                    matlab.ui.control.Label
        J_mppAm2Label                  matlab.ui.control.Label
        Rs_bottom                      matlab.ui.control.NumericEditField
        R_sOmegacdotmLabel_2           matlab.ui.control.Label
        Rsh_bottom                     matlab.ui.control.NumericEditField
        R_shOmegacdotmLabel_2          matlab.ui.control.Label
        n_bottom                       matlab.ui.control.NumericEditField
        nLabel_2                       matlab.ui.control.Label
        I0_bottom                      matlab.ui.control.NumericEditField
        J_0Am2Label                    matlab.ui.control.Label
        Iph_bottom                     matlab.ui.control.NumericEditField
        J_phAm2Label_2                 matlab.ui.control.Label
        Rs_top                         matlab.ui.control.NumericEditField
        Rsh_top                        matlab.ui.control.NumericEditField
        n_top                          matlab.ui.control.NumericEditField
        I0_top                         matlab.ui.control.NumericEditField
        Iph_top                        matlab.ui.control.NumericEditField
        R_sOmegacdotmLabel             matlab.ui.control.Label
        R_shOmegacdotmLabel            matlab.ui.control.Label
        nLabel                         matlab.ui.control.Label
        J_0Am2Label_2                  matlab.ui.control.Label
        J_phAm2Label                   matlab.ui.control.Label
        CustomFigure                   matlab.ui.control.UIAxes
        AirmassvariationTab            matlab.ui.container.Tab
        J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel_2  matlab.ui.control.Label
        phiEphotonfluxm2nmLabel_4      matlab.ui.control.Label
        lambda_bothcdotcE_gbotLabel_3  matlab.ui.control.Label
        lambda_tophcdotcE_gtopLabel_3  matlab.ui.control.Label
        IdealcurrentabsorptionLabel_3  matlab.ui.control.Label
        J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_3  matlab.ui.control.Label
        J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_3  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        bottomcellLabel_4              matlab.ui.control.Label
        TopcellLabel_6                 matlab.ui.control.Label
        R_sOmegacdotmLabel_7           matlab.ui.control.Label
        R_shOmegacdotmLabel_7          matlab.ui.control.Label
        nLabel_7                       matlab.ui.control.Label
        E_gboteVLabel_2                matlab.ui.control.Label
        TandemParameter_AM             matlab.ui.control.DropDown
        WhichtandemIVparameterDropDownLabel  matlab.ui.control.Label
        CurrentChoice_AM               matlab.ui.control.DropDown
        WhichtypeofcurrentdoyouwanttoseeDropDownLabel  matlab.ui.control.Label
        Rs_bottom_AM                   matlab.ui.control.NumericEditField
        Rsh_bottom_AM                  matlab.ui.control.NumericEditField
        n_bottom_AM                    matlab.ui.control.NumericEditField
        Eg_bottom_AM                   matlab.ui.control.NumericEditField
        Rs_top_AM                      matlab.ui.control.NumericEditField
        Rsh_top_AM                     matlab.ui.control.NumericEditField
        n_top_AM                       matlab.ui.control.NumericEditField
        Eg_top_AM                      matlab.ui.control.NumericEditField
        R_sOmegacdotmLabel_3           matlab.ui.control.Label
        R_shOmegacdotmLabel_3          matlab.ui.control.Label
        nLabel_3                       matlab.ui.control.Label
        E_gtopeVLabel                  matlab.ui.control.Label
        AMFigure                       matlab.ui.control.UIAxes
        BandgapoptimizationTab         matlab.ui.container.Tab
        J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel  matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        bottomcellLabel_3              matlab.ui.control.Label
        R_sOmegacdotmLabel_8           matlab.ui.control.Label
        R_shOmegacdotmLabel_8          matlab.ui.control.Label
        nLabel_8                       matlab.ui.control.Label
        E_gboteVLabel                  matlab.ui.control.Label
        TopcellLabel_5                 matlab.ui.control.Label
        TandemParameter_Eg             matlab.ui.control.DropDown
        WhichtandemIVparameterDropDownLabel_2  matlab.ui.control.Label
        CurrentChoice_Eg               matlab.ui.control.DropDown
        WhichtypeofcurrentdoyouwanttoseeDropDownLabel_2  matlab.ui.control.Label
        phiEphotonfluxm2nmLabel_3      matlab.ui.control.Label
        lambda_bothcdotcE_gbotLabel_2  matlab.ui.control.Label
        lambda_tophcdotcE_gtopLabel_2  matlab.ui.control.Label
        IdealcurrentabsorptionLabel_2  matlab.ui.control.Label
        J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_2  matlab.ui.control.Label
        J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_2  matlab.ui.control.Label
        AirmassSlider                  matlab.ui.control.Slider
        AirmassSliderLabel             matlab.ui.control.Label
        Rs_bottom_Eg                   matlab.ui.control.NumericEditField
        Rsh_bottom_Eg                  matlab.ui.control.NumericEditField
        n_bottom_Eg                    matlab.ui.control.NumericEditField
        Eg_bottom_Eg                   matlab.ui.control.NumericEditField
        Rs_top_Eg                      matlab.ui.control.NumericEditField
        Rsh_top_Eg                     matlab.ui.control.NumericEditField
        n_top_Eg                       matlab.ui.control.NumericEditField
        R_sOmegacdotmLabel_5           matlab.ui.control.Label
        R_shOmegacdotmLabel_5          matlab.ui.control.Label
        nLabel_5                       matlab.ui.control.Label
        EgFigure                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartGUI(app)
            [app] = simulateCustom(app);
            [app] = simulateAM(app);
            [app] = simulateEg(app);
        end

        % Value changed function: Iph_top
        function changeJphTop(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: I0_top
        function changeJ0Top(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: n_top
        function changeNTop(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: Rsh_top
        function changeRshTop(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: Rs_top
        function changeRsTop(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: Iph_bottom
        function changeJphBottom(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: I0_bottom
        function changeJ0Bottom(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: n_bottom
        function changeNBottom(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: Rsh_bottom
        function changeRshBottom(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: Rs_bottom
        function changeRsBottom(app, event)
            [app] = simulateCustom(app);
        end

        % Value changed function: Eg_top_AM
        function changeEgTopAM(app, event)
            [app] = simulateAM(app);
        end

        % Callback function: not associated with a component
        function changeJ0Top_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: n_top_AM
        function changeNTop_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: Rsh_top_AM
        function changeRshTop_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: Rs_top_AM
        function changeRsTop_AM(app, event)
            [app] = simulateAM(app);
        end

        % Callback function
        function changeEGBotAM(app, event)
            [app] = simulateAM(app);
        end

        % Callback function: not associated with a component
        function changeJ0Bottom_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: Eg_bottom_AM
        function changeEGBottom_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: n_bottom_AM
        function changeNBottom_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: Rsh_bottom_AM
        function changeRshBottom_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: Rs_bottom_AM
        function changeRsBottom_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: TandemParameter_AM
        function changeTandemParam_AM(app, event)
            [app] = simulateAM(app);
        end

        % Value changed function: CurrentChoice_AM
        function changeCurrentChoice_AM(app, event)
            [app] = simulateAM(app);
        end

        % Callback function: not associated with a component
        function changeJ0Top_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: n_top_Eg
        function changeNTop_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: Rsh_top_Eg
        function changeRshTop_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: Rs_top_Eg
        function changeRsTop_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: Eg_bottom_Eg
        function changeEgBottom_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Callback function: not associated with a component
        function changeJ0Bottom_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: n_bottom_Eg
        function changeNBottom_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: Rsh_bottom_Eg
        function changeRshBottom_Eg(app, event)
            [app] = simulateEg(app);            
        end

        % Value changed function: Rs_bottom_Eg
        function changeRsBottom_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: AirmassSlider
        function ChangeAM(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: CurrentChoice_Eg
        function changeCurrentChoice_Eg(app, event)
            [app] = simulateEg(app);
        end

        % Value changed function: TandemParameter_Eg
        function changeTandemParam_Eg(app, event)
            [app] = simulateEg(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Title = 'Custom';
            app.CustomFigure = uiaxes(app.CustomTab);
            title(app.CustomFigure, 'Title')
            xlabel(app.CustomFigure, 'X')
            ylabel(app.CustomFigure, 'Y')
            zlabel(app.CustomFigure, 'Z')
            app.J_phAm2Label = uilabel(app.CustomTab);
            app.J_phAm2Label.Interpreter = 'tex';
            app.J_phAm2Label.Text = 'J_{ph} [A/m^2]';
            app.J_0Am2Label_2 = uilabel(app.CustomTab);
            app.J_0Am2Label_2.Interpreter = 'tex';
            app.J_0Am2Label_2.Text = 'J_0 [A/m^2]';
            app.nLabel = uilabel(app.CustomTab);
            app.nLabel.Interpreter = 'tex';
            app.nLabel.Text = 'n [-]';
            app.R_shOmegacdotmLabel = uilabel(app.CustomTab);
            app.R_shOmegacdotmLabel.Interpreter = 'tex';
            app.R_shOmegacdotmLabel.Text = 'R_{sh} [\Omega\cdot m]';
            app.R_sOmegacdotmLabel = uilabel(app.CustomTab);
            app.R_sOmegacdotmLabel.Interpreter = 'tex';
            app.R_sOmegacdotmLabel.Text = 'R_s [\Omega\cdot m]';
            app.Iph_top = uieditfield(app.CustomTab, 'numeric');
            app.Iph_top.ValueChangedFcn = createCallbackFcn(app, @changeJphTop, true);
            app.Iph_top.Value = 190;
            app.I0_top = uieditfield(app.CustomTab, 'numeric');
            app.I0_top.ValueChangedFcn = createCallbackFcn(app, @changeJ0Top, true);
            app.I0_top.Value = 4e-14;
            app.n_top = uieditfield(app.CustomTab, 'numeric');
            app.n_top.ValueChangedFcn = createCallbackFcn(app, @changeNTop, true);
            app.n_top.Value = 1.3;
            app.Rsh_top = uieditfield(app.CustomTab, 'numeric');
            app.Rsh_top.ValueChangedFcn = createCallbackFcn(app, @changeRshTop, true);
            app.Rsh_top.Value = 0.108;
            app.Rs_top = uieditfield(app.CustomTab, 'numeric');
            app.Rs_top.ValueChangedFcn = createCallbackFcn(app, @changeRsTop, true);
            app.Rs_top.Value = 3e-08;
            app.J_phAm2Label_2 = uilabel(app.CustomTab);
            app.J_phAm2Label_2.Interpreter = 'tex';
            app.J_phAm2Label_2.Text = 'J_{ph} [A/m^2]';
            app.Iph_bottom = uieditfield(app.CustomTab, 'numeric');
            app.Iph_bottom.ValueChangedFcn = createCallbackFcn(app, @changeJphBottom, true);
            app.Iph_bottom.Value = 195;
            app.J_0Am2Label = uilabel(app.CustomTab);
            app.J_0Am2Label.Interpreter = 'tex';
            app.J_0Am2Label.Text = 'J_0 [A/m^2]';
            app.I0_bottom = uieditfield(app.CustomTab, 'numeric');
            app.I0_bottom.ValueChangedFcn = createCallbackFcn(app, @changeJ0Bottom, true);
            app.I0_bottom.Value = 6e-08;
            app.nLabel_2 = uilabel(app.CustomTab);
            app.nLabel_2.Interpreter = 'tex';
            app.nLabel_2.Text = 'n [-]';
            app.n_bottom = uieditfield(app.CustomTab, 'numeric');
            app.n_bottom.ValueChangedFcn = createCallbackFcn(app, @changeNBottom, true);
            app.n_bottom.Value = 1.3;
            app.R_shOmegacdotmLabel_2 = uilabel(app.CustomTab);
            app.R_shOmegacdotmLabel_2.Interpreter = 'tex';
            app.R_shOmegacdotmLabel_2.Text = 'R_{sh} [\Omega\cdot m]';
            app.Rsh_bottom = uieditfield(app.CustomTab, 'numeric');
            app.Rsh_bottom.ValueChangedFcn = createCallbackFcn(app, @changeRshBottom, true);
            app.Rsh_bottom.Value = 0.2;
            app.R_sOmegacdotmLabel_2 = uilabel(app.CustomTab);
            app.R_sOmegacdotmLabel_2.Interpreter = 'tex';
            app.R_sOmegacdotmLabel_2.Text = 'R_s [\Omega\cdot m]';
            app.Rs_bottom = uieditfield(app.CustomTab, 'numeric');
            app.Rs_bottom.ValueChangedFcn = createCallbackFcn(app, @changeRsBottom, true);
            app.Rs_bottom.Value = 1e-09;
            app.J_mppAm2Label = uilabel(app.CustomTab);
            app.J_mppAm2Label.Interpreter = 'tex';
            app.J_mppAm2Label.Text = 'J_{mpp} [A/m^2]';
            app.V_mppVLabel = uilabel(app.CustomTab);
            app.V_mppVLabel.Interpreter = 'tex';
            app.V_mppVLabel.Text = 'V_{mpp} [V]';
            app.FFLabel = uilabel(app.CustomTab);
            app.FFLabel.Interpreter = 'tex';
            app.FFLabel.Text = 'FF [%]';
            app.PowerWm2Label = uilabel(app.CustomTab);
            app.PowerWm2Label.Interpreter = 'tex';
            app.PowerWm2Label.Text = 'Power[W/m^2]';
            app.TopLabel = uilabel(app.CustomTab);
            app.TopLabel.Interpreter = 'tex';
            app.TopLabel.Text = 'Top';
            app.BottomLabel = uilabel(app.CustomTab);
            app.BottomLabel.Interpreter = 'tex';
            app.BottomLabel.Text = 'Bottom';
            app.TandemLabel = uilabel(app.CustomTab);
            app.TandemLabel.Interpreter = 'tex';
            app.TandemLabel.Text = 'Tandem';
            app.JmppTop = uieditfield(app.CustomTab, 'numeric');
            app.JmppTop.Editable = 'off';
            app.JmppBot = uieditfield(app.CustomTab, 'numeric');
            app.JmppBot.Editable = 'off';
            app.JmppTandem = uieditfield(app.CustomTab, 'numeric');
            app.JmppTandem.Editable = 'off';
            app.VmppTandem = uieditfield(app.CustomTab, 'numeric');
            app.VmppTandem.Editable = 'off';
            app.VmppTop = uieditfield(app.CustomTab, 'numeric');
            app.VmppTop.Editable = 'off';
            app.VmppBot = uieditfield(app.CustomTab, 'numeric');
            app.VmppBot.Editable = 'off';
            app.FFTandem = uieditfield(app.CustomTab, 'numeric');
            app.FFTandem.Editable = 'off';
            app.FFTop = uieditfield(app.CustomTab, 'numeric');
            app.FFTop.Editable = 'off';
            app.FFBot = uieditfield(app.CustomTab, 'numeric');
            app.FFBot.Editable = 'off';
            app.PowerTandem = uieditfield(app.CustomTab, 'numeric');
            app.PowerTandem.Editable = 'off';
            app.PowerTop = uieditfield(app.CustomTab, 'numeric');
            app.PowerTop.Editable = 'off';
            app.PowerBot = uieditfield(app.CustomTab, 'numeric');
            app.PowerBot.Editable = 'off';
            app.V_ocVLabel = uilabel(app.CustomTab);
            app.V_ocVLabel.Interpreter = 'tex';
            app.V_ocVLabel.Text = 'V_{oc} [V]';
            app.VocTop = uieditfield(app.CustomTab, 'numeric');
            app.VocTop.Editable = 'off';
            app.VocBot = uieditfield(app.CustomTab, 'numeric');
            app.VocBot.Editable = 'off';
            app.VocTandem = uieditfield(app.CustomTab, 'numeric');
            app.VocTandem.Editable = 'off';
            app.J_scAm2Label = uilabel(app.CustomTab);
            app.J_scAm2Label.Interpreter = 'tex';
            app.J_scAm2Label.Text = 'J_{sc} [A/m^2]';
            app.JscTop = uieditfield(app.CustomTab, 'numeric');
            app.JscTop.Editable = 'off';
            app.JscBot = uieditfield(app.CustomTab, 'numeric');
            app.JscBot.Editable = 'off';
            app.JscTandem = uieditfield(app.CustomTab, 'numeric');
            app.JscTandem.Editable = 'off';
            app.Image = uiimage(app.CustomTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Figures', 'Schematic.png');
            app.TopcellLabel_7 = uilabel(app.CustomTab);
            app.TopcellLabel_7.Text = 'Top cell';
            app.bottomcellLabel_5 = uilabel(app.CustomTab);
            app.bottomcellLabel_5.Text = 'bottom cell';
            app.AirmassvariationTab = uitab(app.TabGroup);
            app.AirmassvariationTab.Title = 'Airmass variation';
            app.AMFigure = uiaxes(app.AirmassvariationTab);
            title(app.AMFigure, 'Title')
            xlabel(app.AMFigure, 'X')
            ylabel(app.AMFigure, 'Y')
            zlabel(app.AMFigure, 'Z')
            app.E_gtopeVLabel = uilabel(app.AirmassvariationTab);
            app.E_gtopeVLabel.Interpreter = 'tex';
            app.E_gtopeVLabel.Text = 'E_{g,top} [eV]';
            app.nLabel_3 = uilabel(app.AirmassvariationTab);
            app.nLabel_3.Interpreter = 'tex';
            app.nLabel_3.Text = 'n [-]';
            app.R_shOmegacdotmLabel_3 = uilabel(app.AirmassvariationTab);
            app.R_shOmegacdotmLabel_3.Interpreter = 'tex';
            app.R_shOmegacdotmLabel_3.Text = 'R_{sh} [\Omega\cdot m]';
            app.R_sOmegacdotmLabel_3 = uilabel(app.AirmassvariationTab);
            app.R_sOmegacdotmLabel_3.Interpreter = 'tex';
            app.R_sOmegacdotmLabel_3.Text = 'R_s [\Omega\cdot m]';
            app.Eg_top_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.Eg_top_AM.ValueChangedFcn = createCallbackFcn(app, @changeEgTopAM, true);
            app.Eg_top_AM.Value = 1.68;
            app.n_top_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.n_top_AM.ValueChangedFcn = createCallbackFcn(app, @changeNTop_AM, true);
            app.n_top_AM.Value = 1.3;
            app.Rsh_top_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.Rsh_top_AM.ValueChangedFcn = createCallbackFcn(app, @changeRshTop_AM, true);
            app.Rsh_top_AM.Value = 0.108;
            app.Rs_top_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.Rs_top_AM.ValueChangedFcn = createCallbackFcn(app, @changeRsTop_AM, true);
            app.Rs_top_AM.Value = 3e-08;
            app.Eg_bottom_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.Eg_bottom_AM.ValueChangedFcn = createCallbackFcn(app, @changeEGBottom_AM, true);
            app.Eg_bottom_AM.Value = 1.12;
            app.n_bottom_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.n_bottom_AM.ValueChangedFcn = createCallbackFcn(app, @changeNBottom_AM, true);
            app.n_bottom_AM.Value = 1.3;
            app.Rsh_bottom_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.Rsh_bottom_AM.ValueChangedFcn = createCallbackFcn(app, @changeRshBottom_AM, true);
            app.Rsh_bottom_AM.Value = 0.2;
            app.Rs_bottom_AM = uieditfield(app.AirmassvariationTab, 'numeric');
            app.Rs_bottom_AM.ValueChangedFcn = createCallbackFcn(app, @changeRsBottom_AM, true);
            app.Rs_bottom_AM.Value = 1e-09;
            app.WhichtypeofcurrentdoyouwanttoseeDropDownLabel = uilabel(app.AirmassvariationTab);
            app.WhichtypeofcurrentdoyouwanttoseeDropDownLabel.Text = 'Which type of current do you want to see?';
            app.CurrentChoice_AM = uidropdown(app.AirmassvariationTab);
            app.CurrentChoice_AM.Items = {'PH', 'MPP'};
            app.CurrentChoice_AM.ValueChangedFcn = createCallbackFcn(app, @changeCurrentChoice_AM, true);
            app.CurrentChoice_AM.Value = 'PH';
            app.WhichtandemIVparameterDropDownLabel = uilabel(app.AirmassvariationTab);
            app.WhichtandemIVparameterDropDownLabel.Text = 'Which tandem IV parameter?';
            app.TandemParameter_AM = uidropdown(app.AirmassvariationTab);
            app.TandemParameter_AM.Items = {'Pmpp', 'FF', 'Voc', 'Jsc'};
            app.TandemParameter_AM.ValueChangedFcn = createCallbackFcn(app, @changeTandemParam_AM, true);
            app.TandemParameter_AM.Value = 'Pmpp';
            app.E_gboteVLabel_2 = uilabel(app.AirmassvariationTab);
            app.E_gboteVLabel_2.Interpreter = 'tex';
            app.E_gboteVLabel_2.Text = 'E_{g,bot} [eV]';
            app.nLabel_7 = uilabel(app.AirmassvariationTab);
            app.nLabel_7.Interpreter = 'tex';
            app.nLabel_7.Text = 'n [-]';
            app.R_shOmegacdotmLabel_7 = uilabel(app.AirmassvariationTab);
            app.R_shOmegacdotmLabel_7.Interpreter = 'tex';
            app.R_shOmegacdotmLabel_7.Text = 'R_{sh} [\Omega\cdot m]';
            app.R_sOmegacdotmLabel_7 = uilabel(app.AirmassvariationTab);
            app.R_sOmegacdotmLabel_7.Interpreter = 'tex';
            app.R_sOmegacdotmLabel_7.Text = 'R_s [\Omega\cdot m]';
            app.TopcellLabel_6 = uilabel(app.AirmassvariationTab);
            app.TopcellLabel_6.Text = 'Top cell';
            app.bottomcellLabel_4 = uilabel(app.AirmassvariationTab);
            app.bottomcellLabel_4.Text = 'bottom cell';
            app.Image_2 = uiimage(app.AirmassvariationTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Figures', 'Schematic.png');
            app.J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_3 = uilabel(app.AirmassvariationTab);
            app.J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_3.Interpreter = 'tex';
            app.J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_3.Text = 'J_{ph,top} = \int_{0}^{\lambda_{top}} q\cdot \phi(\lambda)d\lambda';
            app.J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_3 = uilabel(app.AirmassvariationTab);
            app.J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_3.Interpreter = 'tex';
            app.J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_3.Text = 'J_{ph,bot} = \int_{\lambda_{top}}^{\lambda_{bot}} q\cdot \phi(\lambda)d\lambda';
            app.IdealcurrentabsorptionLabel_3 = uilabel(app.AirmassvariationTab);
            app.IdealcurrentabsorptionLabel_3.Interpreter = 'tex';
            app.IdealcurrentabsorptionLabel_3.Text = 'Ideal current absorption';
            app.lambda_tophcdotcE_gtopLabel_3 = uilabel(app.AirmassvariationTab);
            app.lambda_tophcdotcE_gtopLabel_3.Interpreter = 'tex';
            app.lambda_tophcdotcE_gtopLabel_3.Text = '\lambda_{top} = h\cdot c/E_{g,top}';
            app.lambda_bothcdotcE_gbotLabel_3 = uilabel(app.AirmassvariationTab);
            app.lambda_bothcdotcE_gbotLabel_3.Interpreter = 'tex';
            app.lambda_bothcdotcE_gbotLabel_3.Text = '\lambda_{bot} = h\cdot c/E_{g,bot}';
            app.phiEphotonfluxm2nmLabel_4 = uilabel(app.AirmassvariationTab);
            app.phiEphotonfluxm2nmLabel_4.Interpreter = 'tex';
            app.phiEphotonfluxm2nmLabel_4.Text = '\phi(E) = photon flux [#/m^2/nm]';
            app.J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel_2 = uilabel(app.AirmassvariationTab);
            app.J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel_2.Interpreter = 'tex';
            app.J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel_2.Text = 'J_{0} = C_{scale}\int_{E_g}^{\infty} 2\cdot\pi\cdot (h^3\cdot c^2)^{-1} E^2 (e^{E/k/T}-1)^{-1}dE';
            app.BandgapoptimizationTab = uitab(app.TabGroup);
            app.BandgapoptimizationTab.Title = 'Bandgap optimization';
            app.EgFigure = uiaxes(app.BandgapoptimizationTab);
            title(app.EgFigure, 'Title')
            xlabel(app.EgFigure, 'X')
            ylabel(app.EgFigure, 'Y')
            zlabel(app.EgFigure, 'Z')
            app.nLabel_5 = uilabel(app.BandgapoptimizationTab);
            app.nLabel_5.Interpreter = 'tex';
            app.nLabel_5.Text = 'n [-]';
            app.R_shOmegacdotmLabel_5 = uilabel(app.BandgapoptimizationTab);
            app.R_shOmegacdotmLabel_5.Interpreter = 'tex';
            app.R_shOmegacdotmLabel_5.Text = 'R_{sh} [\Omega\cdot m]';
            app.R_sOmegacdotmLabel_5 = uilabel(app.BandgapoptimizationTab);
            app.R_sOmegacdotmLabel_5.Interpreter = 'tex';
            app.R_sOmegacdotmLabel_5.Text = 'R_s [\Omega\cdot m]';
            app.n_top_Eg = uieditfield(app.BandgapoptimizationTab, 'numeric');
            app.n_top_Eg.ValueChangedFcn = createCallbackFcn(app, @changeNTop_Eg, true);
            app.n_top_Eg.Value = 1.3;
            app.Rsh_top_Eg = uieditfield(app.BandgapoptimizationTab, 'numeric');
            app.Rsh_top_Eg.ValueChangedFcn = createCallbackFcn(app, @changeRshTop_Eg, true);
            app.Rsh_top_Eg.Value = 0.108;
            app.Rs_top_Eg = uieditfield(app.BandgapoptimizationTab, 'numeric');
            app.Rs_top_Eg.ValueChangedFcn = createCallbackFcn(app, @changeRsTop_Eg, true);
            app.Rs_top_Eg.Value = 3e-08;
            app.Eg_bottom_Eg = uieditfield(app.BandgapoptimizationTab, 'numeric');
            app.Eg_bottom_Eg.ValueChangedFcn = createCallbackFcn(app, @changeEgBottom_Eg, true);
            app.Eg_bottom_Eg.Value = 1.12;
            app.n_bottom_Eg = uieditfield(app.BandgapoptimizationTab, 'numeric');
            app.n_bottom_Eg.ValueChangedFcn = createCallbackFcn(app, @changeNBottom_Eg, true);
            app.n_bottom_Eg.Value = 1.3;
            app.Rsh_bottom_Eg = uieditfield(app.BandgapoptimizationTab, 'numeric');
            app.Rsh_bottom_Eg.ValueChangedFcn = createCallbackFcn(app, @changeRshBottom_Eg, true);
            app.Rsh_bottom_Eg.Value = 0.2;
            app.Rs_bottom_Eg = uieditfield(app.BandgapoptimizationTab, 'numeric');
            app.Rs_bottom_Eg.ValueChangedFcn = createCallbackFcn(app, @changeRsBottom_Eg, true);
            app.Rs_bottom_Eg.Value = 1e-09;
            app.AirmassSliderLabel = uilabel(app.BandgapoptimizationTab);
            app.AirmassSliderLabel.Text = 'Air mass';
            app.AirmassSlider = uislider(app.BandgapoptimizationTab);
            app.AirmassSlider.Limits = [1 5];
            app.AirmassSlider.ValueChangedFcn = createCallbackFcn(app, @ChangeAM, true);
            app.AirmassSlider.Value = 1.5;
            app.J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_2 = uilabel(app.BandgapoptimizationTab);
            app.J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_2.Interpreter = 'tex';
            app.J_phtopint_0lambda_topqcdotphilambdadlambdaLabel_2.Text = 'J_{ph,top} = \int_{0}^{\lambda_{top}} q\cdot \phi(\lambda)d\lambda';
            app.J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_2 = uilabel(app.BandgapoptimizationTab);
            app.J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_2.Interpreter = 'tex';
            app.J_phbotint_lambda_toplambda_botqcdotphilambdadlambdaLabel_2.Text = 'J_{ph,bot} = \int_{\lambda_{top}}^{\lambda_{bot}} q\cdot \phi(\lambda)d\lambda';
            app.IdealcurrentabsorptionLabel_2 = uilabel(app.BandgapoptimizationTab);
            app.IdealcurrentabsorptionLabel_2.Interpreter = 'tex';
            app.IdealcurrentabsorptionLabel_2.Text = 'Ideal current absorption';
            app.lambda_tophcdotcE_gtopLabel_2 = uilabel(app.BandgapoptimizationTab);
            app.lambda_tophcdotcE_gtopLabel_2.Interpreter = 'tex';
            app.lambda_tophcdotcE_gtopLabel_2.Text = '\lambda_{top} = h\cdot c/E_{g,top}';
            app.lambda_bothcdotcE_gbotLabel_2 = uilabel(app.BandgapoptimizationTab);
            app.lambda_bothcdotcE_gbotLabel_2.Interpreter = 'tex';
            app.lambda_bothcdotcE_gbotLabel_2.Text = '\lambda_{bot} = h\cdot c/E_{g,bot}';
            app.phiEphotonfluxm2nmLabel_3 = uilabel(app.BandgapoptimizationTab);
            app.phiEphotonfluxm2nmLabel_3.Interpreter = 'tex';
            app.phiEphotonfluxm2nmLabel_3.Text = '\phi(E) = photon flux [#/m^2/nm]';
            app.WhichtypeofcurrentdoyouwanttoseeDropDownLabel_2 = uilabel(app.BandgapoptimizationTab);
            app.WhichtypeofcurrentdoyouwanttoseeDropDownLabel_2.Text = 'Which type of current do you want to see?';
            app.CurrentChoice_Eg = uidropdown(app.BandgapoptimizationTab);
            app.CurrentChoice_Eg.Items = {'PH', 'MPP'};
            app.CurrentChoice_Eg.ValueChangedFcn = createCallbackFcn(app, @changeCurrentChoice_Eg, true);
            app.CurrentChoice_Eg.Value = 'PH';
            app.WhichtandemIVparameterDropDownLabel_2 = uilabel(app.BandgapoptimizationTab);
            app.WhichtandemIVparameterDropDownLabel_2.Text = 'Which tandem IV parameter?';
            app.TandemParameter_Eg = uidropdown(app.BandgapoptimizationTab);
            app.TandemParameter_Eg.Items = {'Pmpp', 'FF', 'Voc', 'Jsc'};
            app.TandemParameter_Eg.ValueChangedFcn = createCallbackFcn(app, @changeTandemParam_Eg, true);
            app.TandemParameter_Eg.Value = 'Pmpp';
            app.TopcellLabel_5 = uilabel(app.BandgapoptimizationTab);
            app.TopcellLabel_5.Text = 'Top cell';
            app.E_gboteVLabel = uilabel(app.BandgapoptimizationTab);
            app.E_gboteVLabel.Interpreter = 'tex';
            app.E_gboteVLabel.Text = 'E_{g,bot} [eV]';
            app.nLabel_8 = uilabel(app.BandgapoptimizationTab);
            app.nLabel_8.Interpreter = 'tex';
            app.nLabel_8.Text = 'n [-]';
            app.R_shOmegacdotmLabel_8 = uilabel(app.BandgapoptimizationTab);
            app.R_shOmegacdotmLabel_8.Interpreter = 'tex';
            app.R_shOmegacdotmLabel_8.Text = 'R_{sh} [\Omega\cdot m]';
            app.R_sOmegacdotmLabel_8 = uilabel(app.BandgapoptimizationTab);
            app.R_sOmegacdotmLabel_8.Interpreter = 'tex';
            app.R_sOmegacdotmLabel_8.Text = 'R_s [\Omega\cdot m]';
            app.bottomcellLabel_3 = uilabel(app.BandgapoptimizationTab);
            app.bottomcellLabel_3.Text = 'bottom cell';
            app.Image_3 = uiimage(app.BandgapoptimizationTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Figures', 'Schematic.png');
            app.J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel = uilabel(app.BandgapoptimizationTab);
            app.J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel.Interpreter = 'tex';
            app.J_0C_scaleint_E_ginfty2cdotpicdoth3cdotc21E2eEkT11dELabel.Text = 'J_{0} = C_{scale}\int_{E_g}^{\infty} 2\cdot\pi\cdot (h^3\cdot c^2)^{-1} E^2 (e^{E/k/T}-1)^{-1}dE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentMismatches_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end